classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxesA         matlab.ui.control.UIAxes
        UIAxesB         matlab.ui.control.UIAxes
        GenerateButton  matlab.ui.control.Button
        UIAxesB2        matlab.ui.control.UIAxes
        UIAxesC         matlab.ui.control.UIAxes
        UIAxesC2        matlab.ui.control.UIAxes
        ShowButton_2    matlab.ui.control.Button
        ShowButton      matlab.ui.control.Button
        ShowButton_3    matlab.ui.control.Button
        ShowButton_4    matlab.ui.control.Button
        ShowButton_5    matlab.ui.control.Button
        UIAxesD         matlab.ui.control.UIAxes
        ShowButton_6    matlab.ui.control.Button
        UIAxesE         matlab.ui.control.UIAxes
        ShowButton_7    matlab.ui.control.Button
        UIAxesF         matlab.ui.control.UIAxes
        ShowButton_8    matlab.ui.control.Button
        UIAxesF2        matlab.ui.control.UIAxes
        ShowButton_9    matlab.ui.control.Button
        UIAxesG         matlab.ui.control.UIAxes
        ShowButton_10   matlab.ui.control.Button
        UIAxesG2        matlab.ui.control.UIAxes
        ShowButton_11   matlab.ui.control.Button
        UIAxesH         matlab.ui.control.UIAxes
        ShowButton_12   matlab.ui.control.Button
        UIAxesH2        matlab.ui.control.UIAxes
        ShowButton_13   matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property3 % Description
        Original_Image =  imread('Original Image.tif');
        Im_After_Laplacian4_Filer = imread('Original Image.tif');
        Im_After_Laplacian8_Filer = imread('Original Image.tif');
        Adding_OI_To_Laplacian4_Filer = imread('Original Image.tif');
        Adding_OI_To_Laplacian8_Filer = imread('Original Image.tif');
        OI_After_Sobel = imread('Original Image.tif');
        SI_Averaged_By5x5 = imread('Original Image.tif');
        Mask_Of_C_Multi_E = imread('Original Image.tif');
        Mask_Of_C2_Multi_E = imread('Original Image.tif');
        Sharpend_Image_BySumOf_A_and_F = imread('Original Image.tif');
        Sharpend_Image_BySumOf_A_and_F2 = imread('Original Image.tif');
        Power_Law_G = imread('Original Image.tif');
        Power_Law_G2 = imread('Original Image.tif');
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Get Original Image
            imshow(app.Original_Image, 'parent', app.UIAxesA);
            % Padding Original Image
            n = 3;
            Padded_OI_With_Zeros=zeros(size(app.Original_Image)+(n-1));
            % Copy im to Padded Image
            [rows, cols] = size(app.Original_Image);
            for r=1:rows
                for c=1:cols
                    Padded_OI_With_Zeros(r+1,c+1) = app.Original_Image(r,c);
                end
            end
            
            app.Im_After_Laplacian4_Filer = zeros(size(app.Original_Image));
            app.Im_After_Laplacian8_Filer = zeros(size(app.Original_Image));
            Laplacian4_Matrix = [0 -1 0 -1 4 -1 0 -1 0];
            Laplacian8_Matrix = [-1 -1 -1 -1 8 -1 -1 -1 -1];
            
            % Multiply 3x3 Laplacian Matrix with padded OI then add it to the new image      
            [rows, cols] = size(Padded_OI_With_Zeros);
            n = 3;
            for r= 1:rows - (n - 1)
                for c=1:cols - (n - 1)
                    Total4 = 0;
                    Total8 = 0;
                    i = 1;
                    for x=1:n
                        for y=1:n
                            Total4 = Total4 + (Padded_OI_With_Zeros(r+x-1,c+y-1) * Laplacian4_Matrix(i));
                            Total8 = Total8 + (Padded_OI_With_Zeros(r+x-1,c+y-1) * Laplacian8_Matrix(i));
                            i=i+1;
                        end
                    end
                   
                    % Add Total to the new image
                    app.Im_After_Laplacian4_Filer(r,c) = Total4;
                    app.Im_After_Laplacian8_Filer(r,c) = Total8;
                   
                end
            end
            % Range new image values from 0 to 255
            app.Im_After_Laplacian4_Filer = uint8(app.Im_After_Laplacian4_Filer);
            app.Im_After_Laplacian8_Filer = uint8(app.Im_After_Laplacian8_Filer);
            imshow(app.Im_After_Laplacian4_Filer, 'parent', app.UIAxesB);
            imshow(app.Im_After_Laplacian8_Filer, 'parent', app.UIAxesB2);
            
            % then Sharpened image obtained by adding Orignal Image With Laplacianed Image
            app.Adding_OI_To_Laplacian4_Filer = zeros(size(app.Original_Image));
            app.Adding_OI_To_Laplacian8_Filer = zeros(size(app.Original_Image));
            [rows, cols] = size(app.Original_Image);
            for r= 1:rows
                for c=1:cols
                    app.Adding_OI_To_Laplacian4_Filer(r, c) = app.Original_Image(r, c) + app.Im_After_Laplacian4_Filer(r, c);
                    app.Adding_OI_To_Laplacian8_Filer(r, c) = app.Original_Image(r, c) + app.Im_After_Laplacian8_Filer(r, c);
                end
            end
            % Range new image values from 0 to 255
            app.Adding_OI_To_Laplacian4_Filer = uint8(app.Adding_OI_To_Laplacian4_Filer);
            app.Adding_OI_To_Laplacian8_Filer = uint8(app.Adding_OI_To_Laplacian8_Filer);
            imshow(app.Adding_OI_To_Laplacian4_Filer, 'parent', app.UIAxesC);
            imshow(app.Adding_OI_To_Laplacian8_Filer, 'parent', app.UIAxesC2);
            
            % Applying Sobel Horizontal and Vertical on Orignal Image
            % Matrix [Z1 Z2 Z3 : Z4 Z5 Z6 : Z7 Z8 Z9]
            
            app.OI_After_Sobel = app.Original_Image;
            % Convert image to double
            IOD = double(app.OI_After_Sobel);
            
            [rows, cols] = size(IOD);
            for r=1:rows - (n-1)
                for c=1:cols - (n-1)
                    % Sobel filter at X direction (Horizontal)
                    % |(Z7 + 2Z8 + Z9) - (Z1 + 2Z2 + Z3)|
                    Sx=((IOD(r+2,c) + 2*IOD(r+2,c+1) + IOD(r+2,c+2)) - (IOD(r,c) + 2*IOD(r,c+1) + IOD(r,c+2)));
                    %Sobel filter at Y direction (Vertical)
                    % |(Z3 + 2Z6 + Z9) - (Z1 + 2Z4 + Z7)|
                    Sy=((IOD(r,c+2) + 2*IOD(r+1,c+2) + IOD(r+2,c+2)) - (IOD(r,c) + 2*IOD(r+1,c) + IOD(r+2,c)));
                   
                    app.OI_After_Sobel(r,c)=sqrt(Sx.^2 + Sy.^2);
                  
                end
            end
            imshow(app.OI_After_Sobel, 'parent', app.UIAxesD);
            
            % Sobel Image smoothed with a 5x5 averaging filter
            n = 5;
            Padded_SI_With_Zeros = zeros(size(app.OI_After_Sobel)+(n-1));
            
            app.SI_Averaged_By5x5 = size(app.OI_After_Sobel);
            
            [rows, cols] = size(app.OI_After_Sobel);
            for r= 1:rows
                for c=1:cols
                    Padded_SI_With_Zeros(r+2,c+2) = app.OI_After_Sobel(r,c);
                end
            end
            
            [rows, cols] = size(Padded_SI_With_Zeros);
            for r= 1:rows - (n - 1)
                for c=1:cols - (n - 1)
                    Total = 0;
                    for x=1:n
                        for y=1:n
                            Total = Total + Padded_SI_With_Zeros(r + x - 1, c + y - 1);
                        end
                    end
                    % Add Total to the new image
                    app.SI_Averaged_By5x5(r,c) = Total / (n*n);
                end
            end
            app.SI_Averaged_By5x5 = uint8(app.SI_Averaged_By5x5);
            imshow(app.SI_Averaged_By5x5, 'parent', app.UIAxesE);
            
            % F) Mask image formed by the Multiplication of C)Adding OI on Lap4 and E)Sobel Image After 5x5 Averaging
            % F2) Mask image formed by the Multiplication of C2)Adding OI on Lap8 and E)Sobel Image After 5x5 Averaging
            app.Mask_Of_C_Multi_E = zeros(size(app.Original_Image));
            app.Mask_Of_C2_Multi_E = zeros(size(app.Original_Image));
            A = im2double(app.Adding_OI_To_Laplacian4_Filer);
            A2 = im2double(app.Adding_OI_To_Laplacian8_Filer);
            B = double(app.SI_Averaged_By5x5);
            [rows, cols] = size(app.Mask_Of_C_Multi_E);
            for r= 1:rows
                for c=1:cols
                    app.Mask_Of_C_Multi_E(r,c) = A(r,c) * B(r,c);
                    app.Mask_Of_C2_Multi_E(r,c) = A2(r,c) * B(r,c);
                end
            end
            app.Mask_Of_C_Multi_E = uint8(app.Mask_Of_C_Multi_E);
            app.Mask_Of_C2_Multi_E = uint8(app.Mask_Of_C2_Multi_E);
            imshow(app.Mask_Of_C_Multi_E, 'parent', app.UIAxesF);
            imshow(app.Mask_Of_C2_Multi_E, 'parent', app.UIAxesF2);
            
            % G) Sharpened Image obtained by the sum of A)OI and F) Mask of C) Multiplied with E)
            % G2) Sharpened Image obtained by the sum of A)OI and F2) Mask of C2) Multiplied with E)
            app.Sharpend_Image_BySumOf_A_and_F = zeros(size(app.Original_Image));
            app.Sharpend_Image_BySumOf_A_and_F2 = zeros(size(app.Original_Image));
            [rows, cols] = size(app.Original_Image);
            for r= 1:rows
                for c=1:cols
                    app.Sharpend_Image_BySumOf_A_and_F(r, c) = app.Original_Image(r, c) + app.Mask_Of_C_Multi_E(r, c);
                    app.Sharpend_Image_BySumOf_A_and_F2(r, c) = app.Original_Image(r, c) + app.Mask_Of_C2_Multi_E(r, c);
                end
            end
            % Range new image values from 0 to 255
            app.Sharpend_Image_BySumOf_A_and_F = uint8(app.Sharpend_Image_BySumOf_A_and_F);
            app.Sharpend_Image_BySumOf_A_and_F2 = uint8(app.Sharpend_Image_BySumOf_A_and_F2);
            imshow(app.Sharpend_Image_BySumOf_A_and_F, 'parent', app.UIAxesG);
            imshow(app.Sharpend_Image_BySumOf_A_and_F2, 'parent', app.UIAxesG2);
            
            % Power Law Transformation on G and G2
            app.Power_Law_G = im2double(app.Sharpend_Image_BySumOf_A_and_F);
            app.Power_Law_G2 = im2double(app.Sharpend_Image_BySumOf_A_and_F2);
            Constant = 1;
            gamma =0.5;
            [rows, cols] = size(app.Power_Law_G);
            for r= 1:rows
                for c=1:cols
                    m = app.Power_Law_G(r, c);
                    n = app.Power_Law_G2(r, c);
                    app.Power_Law_G(r, c) = Constant * m^(gamma);
                    app.Power_Law_G2(r, c) = Constant * m^(gamma);
                end
            end
            imshow(app.Power_Law_G, 'parent', app.UIAxesH);
            imshow(app.Power_Law_G2, 'parent', app.UIAxesH2);
        end

        % Button pushed function: ShowButton_2
        function ShowButton_2Pushed(app, event)
            figure,imshow(app.Original_Image); title('Original Image');
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            figure,imshow(app.Im_After_Laplacian4_Filer); title('Original Image After Laplacian4');
        end

        % Button pushed function: ShowButton_3
        function ShowButton_3Pushed(app, event)
            figure,imshow(app.Im_After_Laplacian8_Filer); title('Original Image After Laplacian8');
        end

        % Button pushed function: ShowButton_4
        function ShowButton_4Pushed(app, event)
            figure,imshow(app.Adding_OI_To_Laplacian4_Filer); title('Original Image After Laplacian4');
        end

        % Button pushed function: ShowButton_5
        function ShowButton_5Pushed(app, event)
            figure,imshow(app.Adding_OI_To_Laplacian8_Filer); title('Original Image After Laplacian8');
        end

        % Button pushed function: ShowButton_6
        function ShowButton_6Pushed(app, event)
            figure,imshow(app.OI_After_Sobel); title('Sobel Gradient');
        end

        % Button pushed function: ShowButton_7
        function ShowButton_7Pushed(app, event)
            figure,imshow(app.SI_Averaged_By5x5); title('Sobel Image After 5x5 Averaging');
        end

        % Button pushed function: ShowButton_8
        function ShowButton_8Pushed(app, event)
             figure,imshow(app.Mask_Of_C_Multi_E); title('F) Mask image formed by the Multiplication of C) and E)');
        end

        % Button pushed function: ShowButton_9
        function ShowButton_9Pushed(app, event)
             figure,imshow(app.Mask_Of_C2_Multi_E); title('F2) Mask image formed by the Multiplication of C2) and E)');
        end

        % Button pushed function: ShowButton_10
        function ShowButton_10Pushed(app, event)
             figure,imshow(app.Sharpend_Image_BySumOf_A_and_F); title('G) Sharpened Image obtained by the sum of A) and F)');
        end

        % Button pushed function: ShowButton_11
        function ShowButton_11Pushed(app, event)
             figure,imshow(app.Sharpend_Image_BySumOf_A_and_F2); title('G) Sharpened Image obtained by the sum of A) and F2)');
        end

        % Button pushed function: ShowButton_12
        function ShowButton_12Pushed(app, event)
             figure,imshow(app.Power_Law_G); title('H) Final result obtained by applying a power law transformation');
        end

        % Button pushed function: ShowButton_13
        function ShowButton_13Pushed(app, event)
                         figure,imshow(app.Power_Law_G2); title('H2) Final result obtained by applying a power law transformation');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesA = uiaxes(app.UIFigure);
            title(app.UIAxesA, 'A) Origingal Image of Body Bone Scan')
            xlabel(app.UIAxesA, 'X')
            ylabel(app.UIAxesA, 'Y')
            app.UIAxesB = uiaxes(app.UIFigure);
            title(app.UIAxesB, 'B) Original Image After Laplacian4')
            xlabel(app.UIAxesB, 'X')
            ylabel(app.UIAxesB, 'Y')
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.UIAxesB2 = uiaxes(app.UIFigure);
            title(app.UIAxesB2, 'B2) Original Image After Laplacian8')
            xlabel(app.UIAxesB2, 'X')
            ylabel(app.UIAxesB2, 'Y')
            app.UIAxesC = uiaxes(app.UIFigure);
            title(app.UIAxesC, 'C) Adding OI on Lap4')
            xlabel(app.UIAxesC, 'X')
            ylabel(app.UIAxesC, 'Y')
            app.UIAxesC2 = uiaxes(app.UIFigure);
            title(app.UIAxesC2, 'C2) Adding OI on Lap8')
            xlabel(app.UIAxesC2, 'X')
            ylabel(app.UIAxesC2, 'Y')
            app.ShowButton_2 = uibutton(app.UIFigure, 'push');
            app.ShowButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_2Pushed, true);
            app.ShowButton_2.Text = 'Show';
            app.ShowButton = uibutton(app.UIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ShowButton_3 = uibutton(app.UIFigure, 'push');
            app.ShowButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_3Pushed, true);
            app.ShowButton_3.Text = 'Show';
            app.ShowButton_4 = uibutton(app.UIFigure, 'push');
            app.ShowButton_4.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_4Pushed, true);
            app.ShowButton_4.Text = 'Show';
            app.ShowButton_5 = uibutton(app.UIFigure, 'push');
            app.ShowButton_5.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_5Pushed, true);
            app.ShowButton_5.Text = 'Show';
            app.UIAxesD = uiaxes(app.UIFigure);
            title(app.UIAxesD, 'D) Original Image After Sobel Gradient')
            xlabel(app.UIAxesD, 'X')
            ylabel(app.UIAxesD, 'Y')
            app.ShowButton_6 = uibutton(app.UIFigure, 'push');
            app.ShowButton_6.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_6Pushed, true);
            app.ShowButton_6.Text = 'Show';
            app.UIAxesE = uiaxes(app.UIFigure);
            title(app.UIAxesE, 'E) Sobel Image After 5x5 Averaging')
            xlabel(app.UIAxesE, 'X')
            ylabel(app.UIAxesE, 'Y')
            app.ShowButton_7 = uibutton(app.UIFigure, 'push');
            app.ShowButton_7.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_7Pushed, true);
            app.ShowButton_7.Text = 'Show';
            app.UIAxesF = uiaxes(app.UIFigure);
            title(app.UIAxesF, 'F) Mask image formed by the Multiplication of C) and E)')
            xlabel(app.UIAxesF, 'X')
            ylabel(app.UIAxesF, 'Y')
            app.ShowButton_8 = uibutton(app.UIFigure, 'push');
            app.ShowButton_8.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_8Pushed, true);
            app.ShowButton_8.Text = 'Show';
            app.UIAxesF2 = uiaxes(app.UIFigure);
            title(app.UIAxesF2, 'F2) Mask image formed by the Multiplication of C2) and E)')
            xlabel(app.UIAxesF2, 'X')
            ylabel(app.UIAxesF2, 'Y')
            app.ShowButton_9 = uibutton(app.UIFigure, 'push');
            app.ShowButton_9.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_9Pushed, true);
            app.ShowButton_9.Text = 'Show';
            app.UIAxesG = uiaxes(app.UIFigure);
            title(app.UIAxesG, 'G) Sharpened Image obtained by the sum of A) and F)')
            xlabel(app.UIAxesG, 'X')
            ylabel(app.UIAxesG, 'Y')
            app.ShowButton_10 = uibutton(app.UIFigure, 'push');
            app.ShowButton_10.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_10Pushed, true);
            app.ShowButton_10.Text = 'Show';
            app.UIAxesG2 = uiaxes(app.UIFigure);
            title(app.UIAxesG2, 'G2) Sharpened Image obtained by the sum of A) and F2)')
            xlabel(app.UIAxesG2, 'X')
            ylabel(app.UIAxesG2, 'Y')
            app.ShowButton_11 = uibutton(app.UIFigure, 'push');
            app.ShowButton_11.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_11Pushed, true);
            app.ShowButton_11.Text = 'Show';
            app.UIAxesH = uiaxes(app.UIFigure);
            title(app.UIAxesH, 'H) Final result obtained by applying a power law transformation')
            xlabel(app.UIAxesH, 'X')
            ylabel(app.UIAxesH, 'Y')
            app.ShowButton_12 = uibutton(app.UIFigure, 'push');
            app.ShowButton_12.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_12Pushed, true);
            app.ShowButton_12.Text = 'Show';
            app.UIAxesH2 = uiaxes(app.UIFigure);
            title(app.UIAxesH2, 'H2) Final result obtained by applying a power law transformation')
            xlabel(app.UIAxesH2, 'X')
            ylabel(app.UIAxesH2, 'Y')
            app.ShowButton_13 = uibutton(app.UIFigure, 'push');
            app.ShowButton_13.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_13Pushed, true);
            app.ShowButton_13.Text = 'Show';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end